classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
        DiameterSlider                  matlab.ui.control.Slider
        DiameterEditFieldLabel          matlab.ui.control.Label
        Diameter                        matlab.ui.control.NumericEditField
        SHDslider                       matlab.ui.control.Slider
        SpillHoleDiameterLabel          matlab.ui.control.Label
        SHD                             matlab.ui.control.NumericEditField
        ofGoresSpinnerLabel             matlab.ui.control.Label
        NOG                             matlab.ui.control.Spinner
        diameterUnits                   matlab.ui.control.Label
        spillholeUnits                  matlab.ui.control.Label
        ParachuteTypeButtonGroup        matlab.ui.container.ButtonGroup
        FlatCircularButton              matlab.ui.control.RadioButton
        HemisphericalButton             matlab.ui.control.RadioButton
        TorridialButton                 matlab.ui.control.RadioButton
        CD10Label                       matlab.ui.control.Label
        CD20Label                       matlab.ui.control.Label
        CD08Label                       matlab.ui.control.Label
        CDLabel                         matlab.ui.control.Label
        CDEditField                     matlab.ui.control.NumericEditField
        Switch                          matlab.ui.control.Switch
        OverrideCDLabel                 matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        RocketMassEditFieldLabel        matlab.ui.control.Label
        RocketMassEditField             matlab.ui.control.NumericEditField
        Lamp                            matlab.ui.control.Lamp
        DescentVelocityEditFieldLabel   matlab.ui.control.Label
        DescentVelocityEditField        matlab.ui.control.NumericEditField
        MassUnits                       matlab.ui.control.Label
        velocityUnits                   matlab.ui.control.Label
        Switch_2                        matlab.ui.control.ToggleSwitch
        ParachuteDescentVelocityandGoreCalculatorLabel  matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        DescentVelocityCalculatorLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
    end

    
    properties (Access = private)
        d % parachute diameter
        n % number of gores
        R % parachute radius
        sD %spillhole diameter
        sR %spillhole radius
        iR %inner radius
        rM %mid radius
        rMM %coordinate to mid point of donut
        iD %inner Diameter
        sA % surfaceArea
        upTo %arclength where radius of spillhole is equal
        w %width function
        wP
        wN
        cD %coefficient of Drag
        m %rocket Mass
        p %air density
        G %gravity
    end
    
    methods (Access = private)
        
        function plotGore(app)
            
%             sMax = app.R * pi/2;
%             app.upTo = sMax;
%             
%             if app.sD ~= 0  %if there is spill hole input
%                 app.upTo = calcSpillHole(app,app.sD);
%             end
%             
            type = getParachuteType(app);
            cla(app.UIAxes) %clears graph each time
         
            switch type(1)
                case 1
                    fplot(app.UIAxes,app.wP, [0 app.upTo], 'r');
                    hold(app.UIAxes,"on")
                    fplot(app.UIAxes,app.wN, [0 app.upTo],'r');
                   
                    axis(app.UIAxes, [-5 80 -30 30]);
                    title(app.UIAxes,'Parachute Gore'); 
                case 2
                    fplot(app.UIAxes,app.wP, [0, app.upTo], 'r');
                    hold(app.UIAxes,"on")
                    fplot(app.UIAxes,app.wN, [0, app.upTo],'r');
                   
                    axis(app.UIAxes, [-5 100 -30 30]);
                    title(app.UIAxes,'Parachute Gore');   
                case 3
                    fplot(app.UIAxes,app.wP, [0, app.upTo], 'r');
                    hold(app.UIAxes,"on")
                    fplot(app.UIAxes,app.wN, [0, app.upTo],'r');
                    
                    axis(app.UIAxes, [-5 100 -30 30]);
                    title(app.UIAxes,'Parachute Gore');   
            end
           
        end
        
        function getGore(app)
            %app.R = diameter / 2;
            %phi = pi / 2;
            %app.n = num; %number of gores
            t = 2 * pi / app.n; %theta for each gore
            
            type = getParachuteType(app);
            
            app.Label.Text = num2str(type(1));
           
            switch type(1)
                case 1 %width functions for Flat Circular
                    app.w = @(r) (app.R - r) * t;
                    app.wP = @(r) (app.R - r) * t / 2;
                    app.wN = @(r) (r - app.R)* t / 2;
                    
                    s = @(r) ((app.R * t) - 2 *(app.sR*t))/t;
                    sMax = s(app.sR);
                    app.upTo = sMax;
                    
%                        if app.sD ~= 0  %if there is spill hole input
%                             app.upTo = app.sR;
%                        end
                case 2 %width functions for Hemispherical
                    app.w = @(s) app.R * cos(s/app.R) * t;
                    app.wP = @(s) (app.R * cos(s/app.R) * t)/2;
                    app.wN = @(s) -(app.R * cos(s/app.R) * t)/2;
                    
                    sMax = app.R * pi/2;
                    app.upTo = sMax;
                    
                        if app.sD ~= 0  %if there is spill hole input
                            app.upTo = calcSpillHole(app);
                        end
                case 3
                    app.rMM = (app.iR + app.R);
                    app.rM = (app.iR + app.R)/2;
                    
                    app.w = @(s) ((app.iR + (app.rM - (app.rM * cos(s/app.rM)))) * t);
                    app.wP = @(s) ((app.iR + (app.rM - (app.rM * cos(s/app.rM)))) * t)/2;
                    app.wN = @(s) -((app.iR + (app.rM - (app.rM * cos(s/app.rM)))) * t)/2;
                    sMax = app.rM * pi;
                    app.upTo = sMax;
            end 
        end
        
        function s = calcSpillHole(app)
            %app.sR = spillDiam / 2;
            s = app.R * acos(app.sR/app.R); %returns arclength where radius is equal
        end
        
        
        function surfaceArea = calcSurfArea(app)
            surfaceArea = 2 * app.n * (integral(app.w, 0, app.upTo)); %2 x for negative portion
            surfaceArea = surfaceArea / 144; %converts into ft^2
        
            app.Label.Text = num2str(surfaceArea);
        end
        
        function descVel = calcDescVal(app)
                app.sA = calcSurfArea(app); %calculates surface area
                
                descVel = sqrt((2 * app.m * app.G)/(app.cD * app.sA * app.p));  
                
                  if descVel <= 20
                        app.Lamp.Color = 'g';
                  elseif descVel <= 25
                        app.Lamp.Color = [1.00,1.00,0.00];
                    else
                        app.Lamp.Color = 'r';
                  end   
        end
        
        function type = getParachuteType(app)
           if app.FlatCircularButton.Value 
               type = [1 .8];
           elseif app.HemisphericalButton.Value
               type = [2 1.0];
           else
               type = [3 2.0];
           end
        end
        
        
        function lb = kgtolb(~, kg)
            lb = kg * 2.20462;
        end
        
        function ft = mtoft(~, m)
            ft = m * 3.28084;
        end
        
        function m = fttom(~, ft)
            m = ft / 3.28084;
        end
        
        function kg = lbtokg(~, lb)
            kg = lb /  2.20462;
        end
        
        function plot3D(app)
         
            type = getParachuteType(app);
            switch type(1)
                case 1 %Flat Circular (cylindrical)
                    theta = linspace(0,2*pi, app.n+1);
                    r = linspace(app.sR,app.R, 10);
                    [theta,r] = meshgrid(theta,r);
                    z = -r/50;
                    [X,Y,Z] = pol2cart(theta, r, z);
                    
                    cla(app.UIAxes_2)
                    surf(app.UIAxes_2,X,Y,Z);
                    colormap(app.UIAxes_2, 'cool');
                    axis(app.UIAxes_2, [-50 50 -50 50 -5 1]);
                    
                case 2 %Hemispherical (spherical)
                    phiMax = acos(app.sR/app.R);
                    phi = linspace(0,phiMax, 5); %app.upto was phimax
                    theta = linspace(0, 2*pi, (app.n + 1));
                    [theta, phi] = meshgrid(theta,phi);
                    rho = app.R;
                    [X,Y,Z] = sph2cart(theta, phi, rho);
                    
                    cla(app.UIAxes_2)
                    surf(app.UIAxes_2,X,Y,Z);
                    colormap(app.UIAxes_2, 'cool');
                    axis(app.UIAxes_2, [-50 50 -50 50 0 50]);
                case 3
                    theta = linspace(0,2*pi, app.n+1);
                    r = linspace((app.rMM - app.rM),(app.rMM + app.rM), 10);
                    [theta,r] = meshgrid(theta,r);
                    z = sqrt(app.rM.^2 - (r - app.rMM).^2);
                    [X,Y,Z] = pol2cart(theta, r, z);
                    
                    cla(app.UIAxes_2)
                    surf(app.UIAxes_2,X,Y,Z);
                    colormap(app.UIAxes_2, 'cool');
                    axis(app.UIAxes_2, [-50 50 -50 50 0 50]);
            end


        end
        
        function change(app)
                getGore(app);
                plotGore(app);
                plot3D(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CDEditField.Visible = 'off';
            app.cD = .8;
            app.p = .0765;
            app.G = 32.1741;
        end

        % Value changed function: Diameter
        function DiameterValueChanged(app, event)
            
            app.DiameterSlider.Value = app.Diameter.Value;   
            app.d = app.Diameter.Value;
            app.R = app.Diameter.Value/2;
            change(app);
            if app.NOG.Value ~= 0 
               change(app);
            end
            
            if app.m ~= 0 
               app.DescentVelocityEditField.Value = calcDescVal(app);
            end
            
        end

        % Value changed function: DiameterSlider
        function DiameterSliderValueChanged(app, event)
            app.Diameter.Value = app.DiameterSlider.Value;
            app.d = app.DiameterSlider.Value;
            app.R = app.Diameter.Value/2;
            if app.NOG.Value ~= 0 
                change(app);
            end
            
            if app.m ~= 0 
               app.DescentVelocityEditField.Value = calcDescVal(app);
            end
        end

        % Value changing function: DiameterSlider
        function DiameterSliderValueChanging(app, event)
            app.Diameter.Value = event.Value;
            app.d = event.Value;
            app.R = app.Diameter.Value/2;
           if app.NOG.Value ~= 0 
                 change(app);
           end
           
           if app.m ~= 0 
               app.DescentVelocityEditField.Value = calcDescVal(app);
            end
        end

        % Value changed function: NOG
        function NOGValueChanged(app, event)
            app.NOG.Value = event.Value;
            app.n = event.Value;
            
            if app.Diameter.Value ~= 0 
                 change(app);
            end
        end

        % Value changing function: NOG
        function NOGValueChanging(app, event)
            app.NOG.Value = event.Value;
            app.n = event.Value;
            
            if app.Diameter.Value ~= 0 
                  change(app);
            end
        end

        % Value changed function: SHD
        function SHDValueChanged(app, event)
            type = getParachuteType(app);
            if type(1) == 3
                app.iD = app.SHD.Value;
                app.iR = app.SHD.Value / 2;
            else
                app.sD = app.SHD.Value;
                app.sR = app.SHD.Value / 2;
            end
            
            app.SHDslider.Value = app.SHD.Value;
            
            if app.Diameter.Value ~= 0 && app.NOG.Value ~= 0
                 change(app);
            end
            
             if app.m ~= 0 
               app.DescentVelocityEditField.Value = calcDescVal(app);
            end
        end

        % Value changed function: SHDslider
        function SHDsliderValueChanged(app, event)
            type = getParachuteType(app);
            if type(1) == 3
                app.iD = app.SHD.Value;
                app.iR = app.SHD.Value / 2;
            else
                app.sD = app.SHD.Value;
                app.sR = app.SHD.Value / 2;
            end
            
            app.SHD.Value = app.SHDslider.Value;
            
            if app.Diameter.Value ~= 0 && app.NOG.Value ~= 0
                  change(app);
            end
            
             if app.m ~= 0 
               app.DescentVelocityEditField.Value = calcDescVal(app);
            end
        end

        % Value changing function: SHDslider
        function SHDsliderValueChanging(app, event)
            type = getParachuteType(app);
            if type(1) == 3
                app.iD = app.SHD.Value;
                app.iR = app.SHD.Value / 2;
            else
                app.sD = app.SHD.Value;
                app.sR = app.SHD.Value / 2;
            end
            
            app.SHD.Value = event.Value;
            
            if app.Diameter.Value ~= 0 && app.NOG.Value ~= 0
                change(app);
            end 
            
             if app.m ~= 0 
               app.DescentVelocityEditField.Value = calcDescVal(app);
            end
        end

        % Value changed function: RocketMassEditField
        function RocketMassEditFieldValueChanged(app, event)
            value = app.RocketMassEditField.Value;
            app.m = value;
            
            if app.Diameter.Value ~= 0 
               app.DescentVelocityEditField.Value = calcDescVal(app);
            end
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value, 'On') 
                app.CDEditField.Visible = 'on';
                app.cD = app.CDEditField.Value;
            else
                app.CDEditField.Visible = 'off';
                parachuteType = getParachuteType(app);
                pt = parachuteType(1,1);
                    switch pt
                        case 1
                            app.cD = .8;
                        case 2
                            app.cD = 1;
                        case 3
                            app.cD = 2;
                    end
            end
        end

        % Value changed function: CDEditField
        function CDEditFieldValueChanged(app, event)
            value = app.CDEditField.Value;
            app.cD = value;
                
            if app.m ~= 0 
               app.DescentVelocityEditField.Value = calcDescVal(app);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Selection changed function: ParachuteTypeButtonGroup
        function ParachuteTypeButtonGroupSelectionChanged(app, event)
            selectedButton = getParachuteType(app);
            switch selectedButton(1)
                case 1
                    app.cD = selectedButton(2);
                    if app.iR ~= 0
                       app.sR = app.iR; 
                    end
             
                    app.SpillHoleDiameterLabel.Text = 'Spill Hole Diameter';
                    app.SpillHoleDiameterLabel.Position = [1,45,108,22];
                    app.spillholeUnits.Position = [116,45,23,20];
                    if app.Diameter.Value ~= 0 && app.NOG.Value ~= 0
                        change(app);
                    end
                case 2
                    app.cD = selectedButton(2); 
                    if app.iR ~= 0
                       app.sR = app.iR; 
                    end
                    
                    
                    app.SpillHoleDiameterLabel.Text = 'Spill Hole Diameter';
                    app.SpillHoleDiameterLabel.Position = [1,45,108,22];
                    app.spillholeUnits.Position = [116,45,23,20];
         
                    if app.Diameter.Value ~= 0 && app.NOG.Value ~= 0
                        change(app);
                    end
                case 3                 
                    app.cD = selectedButton(2);
                    app.iR = app.sR;
                    
                    app.SpillHoleDiameterLabel.Text = 'Inner Diameter';
                    app.SpillHoleDiameterLabel.Position = [1,47,85,22];
                    app.spillholeUnits.Position = [94,49,23,20];
                    if app.Diameter.Value ~= 0 && app.NOG.Value ~= 0 
                        change(app);
                    end
            end
            
           
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            if strcmp(value, 'Metric')
                app.m = lbtokg(app, app.m);
                app.RocketMassEditField.Value = app.m;
                app.p = 1.225;
                app.sA = app.sA / 35.3147;
                app.G = 9.8;
                app.MassUnits.Text = '(kg)';
                app.velocityUnits.Text = '(m/s)';
                app.DescentVelocityEditField.Value = calcDescVal(app);
            else
                app.m = kgtolb(app, app.m);
                app.RocketMassEditField.Value = app.m;
                app.p = .0765;
                app.sA = app.sA * 35.3147;
                app.G = 32.1741;
                app.MassUnits.Text = '(lb)';
                app.velocityUnits.Text = '(ft/s)';
                app.DescentVelocityEditField.Value = calcDescVal(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Parachute Gore')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Panel = uipanel(app.UIFigure);
            app.DiameterSlider = uislider(app.Panel);
            app.DiameterSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.DiameterSlider.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.DiameterSlider.ValueChangedFcn = createCallbackFcn(app, @DiameterSliderValueChanged, true);
            app.DiameterSlider.ValueChangingFcn = createCallbackFcn(app, @DiameterSliderValueChanging, true);
            app.DiameterSlider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.DiameterEditFieldLabel = uilabel(app.Panel);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.Diameter = uieditfield(app.Panel, 'numeric');
            app.Diameter.ValueChangedFcn = createCallbackFcn(app, @DiameterValueChanged, true);
            app.SHDslider = uislider(app.Panel);
            app.SHDslider.Limits = [0 20];
            app.SHDslider.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20];
            app.SHDslider.ValueChangedFcn = createCallbackFcn(app, @SHDsliderValueChanged, true);
            app.SHDslider.ValueChangingFcn = createCallbackFcn(app, @SHDsliderValueChanging, true);
            app.SpillHoleDiameterLabel = uilabel(app.Panel);
            app.SpillHoleDiameterLabel.Text = 'Spill Hole Diameter';
            app.SHD = uieditfield(app.Panel, 'numeric');
            app.SHD.ValueChangedFcn = createCallbackFcn(app, @SHDValueChanged, true);
            app.ofGoresSpinnerLabel = uilabel(app.Panel);
            app.ofGoresSpinnerLabel.Text = '# of Gores';
            app.NOG = uispinner(app.Panel);
            app.NOG.ValueChangingFcn = createCallbackFcn(app, @NOGValueChanging, true);
            app.NOG.ValueChangedFcn = createCallbackFcn(app, @NOGValueChanged, true);
            app.diameterUnits = uilabel(app.Panel);
            app.diameterUnits.Text = '(in)';
            app.spillholeUnits = uilabel(app.Panel);
            app.spillholeUnits.Text = '(in)';
            app.ParachuteTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.ParachuteTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ParachuteTypeButtonGroupSelectionChanged, true);
            app.ParachuteTypeButtonGroup.Title = 'Parachute Type';
            app.FlatCircularButton = uiradiobutton(app.ParachuteTypeButtonGroup);
            app.FlatCircularButton.Text = 'Flat Circular';
            app.FlatCircularButton.Value = true;
            app.HemisphericalButton = uiradiobutton(app.ParachuteTypeButtonGroup);
            app.HemisphericalButton.Text = 'Hemispherical';
            app.TorridialButton = uiradiobutton(app.ParachuteTypeButtonGroup);
            app.TorridialButton.Text = 'Torridial';
            app.CD10Label = uilabel(app.ParachuteTypeButtonGroup);
            app.CD10Label.Text = '*CD = 1.0';
            app.CD20Label = uilabel(app.ParachuteTypeButtonGroup);
            app.CD20Label.Text = '*CD = 2.0';
            app.CD08Label = uilabel(app.ParachuteTypeButtonGroup);
            app.CD08Label.Text = '*CD = 0.8';
            app.CDLabel = uilabel(app.ParachuteTypeButtonGroup);
            app.CDLabel.Text = 'CD =';
            app.CDEditField = uieditfield(app.ParachuteTypeButtonGroup, 'numeric');
            app.CDEditField.ValueChangedFcn = createCallbackFcn(app, @CDEditFieldValueChanged, true);
            app.CDEditField.Value = 1;
            app.Switch = uiswitch(app.ParachuteTypeButtonGroup, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.OverrideCDLabel = uilabel(app.ParachuteTypeButtonGroup);
            app.OverrideCDLabel.Text = '*Override CD';
            app.Panel_2 = uipanel(app.UIFigure);
            app.RocketMassEditFieldLabel = uilabel(app.Panel_2);
            app.RocketMassEditFieldLabel.Text = 'Rocket Mass';
            app.RocketMassEditField = uieditfield(app.Panel_2, 'numeric');
            app.RocketMassEditField.ValueChangedFcn = createCallbackFcn(app, @RocketMassEditFieldValueChanged, true);
            app.Lamp = uilamp(app.Panel_2);
            app.DescentVelocityEditFieldLabel = uilabel(app.Panel_2);
            app.DescentVelocityEditFieldLabel.Text = 'Descent Velocity ';
            app.DescentVelocityEditField = uieditfield(app.Panel_2, 'numeric');
            app.MassUnits = uilabel(app.Panel_2);
            app.MassUnits.Text = '(lb)';
            app.velocityUnits = uilabel(app.Panel_2);
            app.velocityUnits.Text = '(ft/s)';
            app.Switch_2 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_2.Items = {'Imperial', 'Metric'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Imperial';
            app.ParachuteDescentVelocityandGoreCalculatorLabel = uilabel(app.UIFigure);
            app.ParachuteDescentVelocityandGoreCalculatorLabel.Text = 'Parachute Descent Velocity and Gore Calculator';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '3-D Gore')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.DescentVelocityCalculatorLabel = uilabel(app.UIFigure);
            app.DescentVelocityCalculatorLabel.Text = 'Descent Velocity Calculator';
            app.Label = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end