classdef parachuteApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Label                      matlab.ui.control.Label
        ParachuteVisualizationGoreTemplateCreatorLabel  matlab.ui.control.Label
        ParachuteTypeButtonGroup   matlab.ui.container.ButtonGroup
        CD05Label                  matlab.ui.control.Label
        DiskGapBandButton          matlab.ui.control.RadioButton
        OverrideCDLabel            matlab.ui.control.Label
        Switch                     matlab.ui.control.Switch
        CDEditField                matlab.ui.control.NumericEditField
        CDLabel                    matlab.ui.control.Label
        CD08Label                  matlab.ui.control.Label
        CD20Label                  matlab.ui.control.Label
        CD10Label                  matlab.ui.control.Label
        TorridialButton            matlab.ui.control.RadioButton
        HemisphericalButton        matlab.ui.control.RadioButton
        FlatCircularButton         matlab.ui.control.RadioButton
        Panel                      matlab.ui.container.Panel
        ExportasDXFfileExportsBothLinesPanel  matlab.ui.container.Panel
        ExportButton               matlab.ui.control.StateButton
        DXFFileNameEditField       matlab.ui.control.EditField
        DXFFileNameEditFieldLabel  matlab.ui.control.Label
        SeamWidthDropDown          matlab.ui.control.DropDown
        SeamWidthDropDownLabel     matlab.ui.control.Label
        spillholeUnits             matlab.ui.control.Label
        diameterUnits              matlab.ui.control.Label
        NOG                        matlab.ui.control.Spinner
        ofGoresSpinnerLabel        matlab.ui.control.Label
        SHD                        matlab.ui.control.NumericEditField
        SpillHoleDiameterLabel     matlab.ui.control.Label
        SHDslider                  matlab.ui.control.Slider
        Diameter                   matlab.ui.control.NumericEditField
        DiameterEditFieldLabel     matlab.ui.control.Label
        DiameterSlider             matlab.ui.control.Slider
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        d % parachute diameter
        n % number of gores
        R % parachute radius
        sD %spillhole diameter
        sR %spillhole radius
        iR %inner radius
        rM %mid radius
        rMM %coordinate to mid point of donut
        iD %inner Diameter
        sA % surfaceArea
        upTo %arclength where radius of spillhole is equal
        w %width function
        wP %positive line
        wN %negative line
        wPs %positive line for seam
        wNs %negative line for seam
        cD %coefficient of Drag
        m %rocket Mass
        p %air density
        G %gravity
        seam %seam width
        zero % plot up to for seam width
        zeroT % special for torus because plotted backwards
        gap %special for disk gap band
        height %special for disk gap band
        width %special for disk gap band
    end
    
    methods (Access = private)
        
        function plotGore(app)
            
            type = getParachuteType(app);
            cla(app.UIAxes) %clears graph each time
         
            switch type(1)
                case 1
                    hold(app.UIAxes,"on")
                    
                    %width lines
                    fplot(app.UIAxes,app.wP, [0 app.upTo], 'r');
                    fplot(app.UIAxes,app.wN, [0 app.upTo],'r');
                    
                    %vertical lines
                    plot(app.UIAxes, [0,0], [app.wP(0), app.wN(0)], 'r'); 
                    plot(app.UIAxes, [app.upTo,app.upTo], [app.wP(app.upTo), app.wN(app.upTo)],'r');
                    
                    %seam allowance
                    fplot(app.UIAxes, app.wPs,[-app.seam,app.zero], '--b');
                    fplot(app.UIAxes, app.wNs, [-app.seam,app.zero], '--b');
                    
                    %vertical lines for seam
                    plot(app.UIAxes, [-app.seam,-app.seam], [app.wPs(-app.seam), app.wNs(-app.seam)], '--b');
                    plot(app.UIAxes, [app.zero,app.zero], [app.wPs(app.zero), app.wNs(app.zero)], '--b');
                    
                    %{
                    if app.d < 150
                        axis(app.UIAxes, [-5 80 -40 40]);
                    elseif app.d < 210
                        axis(app.UIAxes, [-5 110 -50 50]);
                    else
                        axis(app.UIAxes, [-5 120 -60 60]);
                    end
                    %}
                    %title(app.UIAxes,'Parachute Gore'); 
                case 2
                    hold(app.UIAxes,"on")
                    
                    %width lines
                    fplot(app.UIAxes,app.wP, [0, app.upTo], 'r');
                    fplot(app.UIAxes,app.wN, [0, app.upTo],'r');
                    
                    %vertical lines
                    plot(app.UIAxes, [0,0], [app.wP(0), app.wN(0)], 'r');
                    plot(app.UIAxes, [app.upTo,app.upTo], [app.wP(app.upTo), app.wN(app.upTo)],'r');
                    
                    %seam allowance
                    fplot(app.UIAxes, app.wPs,[-app.seam,app.zero], '--b');
                    fplot(app.UIAxes, app.wNs, [-app.seam,app.zero], '--b');
                    
                    %vertical lines for seam
                    plot(app.UIAxes, [-app.seam,-app.seam], [app.wPs(-app.seam), app.wNs(-app.seam)], '--b');
                    plot(app.UIAxes, [app.zero,app.zero], [app.wPs(app.zero), app.wNs(app.zero)], '--b');
                   
                   % axis(app.UIAxes, [-5 100 -30 30]);
                   % title(app.UIAxes,'Parachute Gore');   
                case 3
                    hold(app.UIAxes,"on")
                    
                    %width lines 
                    fplot(app.UIAxes,app.wP, [0, app.upTo], 'r');
                    fplot(app.UIAxes,app.wN, [0, app.upTo],'r');
                    
                    %vertical lines
                    plot(app.UIAxes, [0,0], [app.wP(0), app.wN(0)], 'r');
                    plot(app.UIAxes, [app.upTo,app.upTo], [app.wP(app.upTo), app.wN(app.upTo)],'r');
                    
                    %seam allowance
                    fplot(app.UIAxes, app.wPs, [-app.seam, app.zero], '--b'); %does not have zero
                    fplot(app.UIAxes, app.wNs, [-app.seam, app.zero], '--b');
                    
                    %vertical lines for seam
                    plot(app.UIAxes, [-app.seam,-app.seam], [app.wPs(-app.seam), app.wNs(-app.seam)], '--b');
                    plot(app.UIAxes, [app.zero,app.zero], [app.wPs(app.zero), app.wNs(app.zero)], '--b');
                    
                    %axis(app.UIAxes, [-5 100 -30 30]);
                   % title(app.UIAxes,'Parachute Gore');   
                case 4
                    hold(app.UIAxes,"on")
                    
                    %MAKE DISK
                    %width lines
                    fplot(app.UIAxes,app.wP, [0 app.upTo], 'r');
                    fplot(app.UIAxes,app.wN, [0 app.upTo],'r');
                    
                    %vertical lines
                    plot(app.UIAxes, [0,0], [app.wP(0), app.wN(0)], 'r'); 
                    plot(app.UIAxes, [app.upTo,app.upTo], [app.wP(app.upTo), app.wN(app.upTo)],'r');
                    
                    %seam allowance
                    fplot(app.UIAxes, app.wPs, [-app.seam, app.zero], '--b');
                    fplot(app.UIAxes, app.wNs, [-app.seam, app.zero], '--b');
                    
                    %vertical lines for seam
                    plot(app.UIAxes, [-app.seam,-app.seam], [app.wPs(-app.seam), app.wNs(-app.seam)], '--b');
                    plot(app.UIAxes, [app.zero,app.zero], [app.wPs(app.zero), app.wNs(app.zero)], '--b');
                    
                    %{
                    if app.d < 30 
                        axis(app.UIAxes, [-20 80 -30 30]);
                    elseif app.d < 150
                        axis(app.UIAxes, [-40 110 -50 50]);
                    else
                        axis(app.UIAxes, [-50 120 -60 60]);
                    end
                    %}

                    
                    %axis(app.UIAxes, 'equal')
                    %title(app.UIAxes,'Parachute Gore'); 
                   
                    
                    %MAKE BAND
                    app.gap = app.d/4 * .15; %determine height of gap 
                    app.height = app.d * .15; %determine height of cylinder
                    app.width = app.wP(0) - app.wN(0); %width of band
                    %rectangle(x,y, width, height)
                    rectangle(app.UIAxes, 'Position', [-app.gap-app.height-6, -app.width/2, app.height ,app.width],'EdgeColor','r');
                    rectangle(app.UIAxes, 'Position', [-app.gap-app.height-6-app.seam, (-app.width/2)-app.seam, app.height+2*app.seam , app.width+2*app.seam],'EdgeColor','b','LineStyle',"--");    
            end
            hold(app.UIAxes, "off");
            %{
            if type(1) == 2 || type(1) == 3 %torroidal and hemispherical scale together
                if app.d < 130 
                    axis(app.UIAxes, [-5 100 -30 30]);
                elseif app.d < 200
                    axis(app.UIAxes, [-5 160 -50 50]);
                else 
                    axis(app.UIAxes, [-5 200 -50 50]);
                end
            end
            %}
            axis(app.UIAxes, 'equal')
        end
        
        function getGore(app) %write seam functions for plotting

            t = 2 * pi / app.n; %theta for each gore
            type = getParachuteType(app);
            
            %app.Label.Text = num2str(app.R);
           
            switch type(1)
                case 1 %width functions for Flat Circular
                    app.w = @(r) (app.R - r) * t;
                    app.wP = @(r) (app.R - r) * t / 2;
                    app.wN = @(r) (r - app.R)* t / 2;
                    
                    %seam functions
                    app.wPs = @(r) app.wP(r) + app.seam; 
                    app.wNs = @(r) app.wN(r) - app.seam;
                    
                    %for spillhole
                    sMax = app.R - app.sR;          
                    app.upTo = sMax;
                    
                    %for seam width
                    if app.sR == 0
                        app.zero = fzero(app.wPs, sMax);
                    else
                        app.zero = sMax + app.seam;
                    end
                    
%                        
                case 2 %width functions for Hemispherical
                    app.w = @(s) app.R * cos(s/app.R) * t;
                    app.wP = @(s) (app.R * cos(s/app.R) * t)/2;
                    app.wN = @(s) -(app.R * cos(s/app.R) * t)/2;
                    
                    %seam functions
                    app.wPs = @(r) app.wP(r) + app.seam; 
                    app.wNs = @(r) app.wN(r) - app.seam;
                    
                    %for spill hole
                    sMax = app.R * pi/2;
                    app.upTo = sMax;
                        if app.sD ~= 0  %if there is spill hole input
                            app.upTo = calcSpillHole(app);
                        end
                    
                    %for seam width
                    if app.sR == 0
                        app.zero = fzero(app.wPs, sMax);
                    else
                        app.zero = app.upTo + app.seam;
                    end
                    
                case 3 %width functions for torroidal
                    app.rM = (app.R - app.iR)/2;
                    app.rMM = (app.iR + app.rM);
                    
                    app.w = @(s) (app.rMM - app.rM * cos(s/app.rM)) * t;
                    app.wP = @(s) (app.rMM - app.rM * cos(s/app.rM)) * t / 2;
                    app.wN = @(s) -(app.rMM - app.rM * cos(s/app.rM)) * t / 2;
                    
                    %seam functions
                    app.wPs = @(r) app.wP(r) + app.seam; 
                    app.wNs = @(r) app.wN(r) - app.seam;
                    
                    sMax = app.rM * pi;
                    app.upTo = sMax;
                                   
                    app.zero = app.upTo + app.seam;
                
                case 4 %width functions for disk gap band
                    app.w = @(r) (app.R - r) * t;
                    app.wP = @(r) (app.R - r) * t / 2;
                    app.wN = @(r) (r - app.R)* t / 2;
                    
                    %seam functions
                    app.wPs = @(r) app.wP(r) + app.seam; 
                    app.wNs = @(r) app.wN(r) - app.seam;
                    
                    %for spillhole
                    sMax = app.R - app.sR;          
                    app.upTo = sMax;
                    
                    %for seam width
                    if app.sR == 0
                        app.zero = fzero(app.wPs, sMax);
                    else
                        app.zero = sMax + app.seam;
                    end
                    
            end 
        end
        
        function s = calcSpillHole(app)
            %app.sR = spillDiam / 2;
            s = app.R * acos(app.sR/app.R); %returns arclength where radius is equal
        end
        
        
        function surfaceArea = calcSurfArea(app)
            surfaceArea = app.n * (integral(app.w, 0, app.upTo)); %2 x for negative portion
            surfaceArea = surfaceArea / 144; %converts into ft^2
        
            app.Label.Text = ['Surface Area: ', num2str(surfaceArea), ' ft^2'];
        end
        
        function descVel = calcDescVal(app)
                app.sA = calcSurfArea(app); %calculates surface area
                
                descVel = sqrt((2 * app.m * app.G)/(app.cD * app.sA * app.p));  
                
                  if descVel <= 20
                        app.Lamp.Color = 'g';
                  elseif descVel <= 25
                        app.Lamp.Color = [1.00,1.00,0.00];
                    else
                        app.Lamp.Color = 'r';
                  end   
        end
        
        function type = getParachuteType(app)
           if app.FlatCircularButton.Value 
               type = [1 .8];
           elseif app.DiskGapBandButton.Value
               type = [4, .5];
           elseif app.HemisphericalButton.Value
               type = [2 1.0];
           else
               type = [3 2.0];
           end
        end
        
        
        function lb = kgtolb(~, kg)
            lb = kg * 2.20462;
        end
        
        function ft = mtoft(~, m)
            ft = m * 3.28084;
        end
        
        function m = fttom(~, ft)
            m = ft / 3.28084;
        end
        
        function kg = lbtokg(~, lb)
            kg = lb /  2.20462;
        end
        
        function plot3D(app)
            cla(app.UIAxes_2)
            type = getParachuteType(app);
            switch type(1)
                case 1 %Flat Circular (cylindrical)
                    theta = linspace(0,2*pi, app.n+1);
                    r = linspace(app.sR,app.R, 10);
                    [theta,r] = meshgrid(theta,r);
                    %z = -r/50;
                    z = r * 0;
                    [X,Y,Z] = pol2cart(theta, r, z);
                    
                    %cla(app.UIAxes_2)
                    surf(app.UIAxes_2,X,Y,Z);
                    %colormap(app.UIAxes_2,"hsv");
                    %axis(app.UIAxes_2, [-50 50 -50 50 -5 1]);
                    %axis(app.UIAxes_2, 'equal');
                case 2 %Hemispherical (spherical)
                    phiMax = acos(app.sR/app.R);
                    phi = linspace(0,phiMax, 10); %app.upto was phimax
                    theta = linspace(0, 2*pi, (app.n + 1));
                    [theta, phi] = meshgrid(theta,phi);
                    rho = app.R;
                    [X,Y,Z] = sph2cart(theta, phi, rho);
                    
                    %cla(app.UIAxes_2)
                    surf(app.UIAxes_2,X,Y,Z);
                    %colormap(app.UIAxes_2, "hsv");
                    %axis(app.UIAxes_2, [-50 50 -50 50 0 50]);
                    %axis(app.UIAxes_2, 'equal');
                    
                case 3 %Torroidal (cylindrical)
                    theta = linspace(0,2*pi, app.n+1);
                    r = linspace((app.rMM - app.rM),(app.rMM + app.rM), 10);
                    [theta,r] = meshgrid(theta,r);
                    z = sqrt(app.rM.^2 - (r - app.rMM).^2);
                    [X,Y,Z] = pol2cart(theta, r, z);
                    
                    %cla(app.UIAxes_2)
                    surf(app.UIAxes_2,X,Y,Z);
                    %colormap(app.UIAxes_2, "hsv");
                    %axis(app.UIAxes_2, [-50 50 -50 50 0 50]);
                    %axis(app.UIAxes_2, 'equal');
                    
                case 4 %Disk Gap Band (cylindrical)
                    
                    %GENERATE DISK
                  
                    theta = linspace(0,2*pi, app.n+1);
                    r = linspace(app.sR,app.R, 10);
                    [theta,r] = meshgrid(theta,r);
                    z = r * 0; %generate z coordinates for cart. z needs to be same size as r
                    z = z + app.height + app.gap;
                    %z = -r/50;
                    [X,Y,Z] = pol2cart(theta, r, z);
                    
                    %GENERATE BAND
                    theta = linspace(0,2*pi, app.n+1); %creating angle segments for cylindrical
                    [theta,r] = meshgrid(theta,app.R); %generate grid for each point in cylindrical

                    z_bot = zeros(1,app.n+1); %origin coordinate
                    z_top = ones(1,app.n+1);
                    z_top = z_top * app.height; %determine height of cylinder
                    z = vertcat(z_bot, z_top); %z-coordinates for cylinder
                    
                    theta = vertcat(theta, theta); %need 2 rows since z is 2 rows
                    r = vertcat(r,r); %need 2 rows since z is 2 rows
                    
                    [cx,cy,cz] = pol2cart(theta, r, z); %cylinder(band) surface coordinates
                    
                    hold(app.UIAxes_2,'on');
                    surf(app.UIAxes_2,X,Y,Z);
                    surf(app.UIAxes_2,cx,cy,cz);
                    %colormap(app.UIAxes_2,"hsv");
                    %axis(app.UIAxes_2, 'equal'); 
            end
            axis(app.UIAxes_2, 'equal');
        end
        
        function change(app)
                getGore(app);
                plotGore(app);
                plot3D(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CDEditField.Visible = 'off';
            app.cD = .8;
            app.p = .0765;
            app.G = 32.1741;
            app.R = 0;
            app.sR = 0;
            app.iR = 0;
            app.n = 0;
            app.m = 0;
            app.seam = 1/4;
        end

        % Value changed function: Diameter
        function DiameterValueChanged(app, event)
            
            app.DiameterSlider.Value = app.Diameter.Value;   
            app.d = app.Diameter.Value;
            app.R = app.Diameter.Value/2;
            change(app);
            if app.NOG.Value ~= 0 
               change(app);
            end
            
            if app.m ~= 0 && app.n ~= 0
               app.DescentVelocityEditField.Value = calcDescVal(app);
            end
            
        end

        % Value changed function: DiameterSlider
        function DiameterSliderValueChanged(app, event)
            app.Diameter.Value = app.DiameterSlider.Value;
            app.d = app.DiameterSlider.Value;
            app.R = app.Diameter.Value/2;
            
            if app.NOG.Value ~= 0 
                change(app);
            end
            
            if app.m ~= 0 && app.n ~= 0
               app.DescentVelocityEditField.Value = calcDescVal(app);
            end
        end

        % Value changing function: DiameterSlider
        function DiameterSliderValueChanging(app, event)
            app.Diameter.Value = event.Value;
            app.d = event.Value;
            app.R = app.Diameter.Value/2;
           if app.NOG.Value ~= 0 
                 change(app);
           end
           
           if app.m ~= 0 && app.n ~= 0
               app.DescentVelocityEditField.Value = calcDescVal(app);
            end
        end

        % Value changed function: NOG
        function NOGValueChanged(app, event)
            app.NOG.Value = event.Value;
            app.n = event.Value;
            
            if app.Diameter.Value ~= 0 
                 change(app);
            end
        end

        % Value changing function: NOG
        function NOGValueChanging(app, event)
            app.NOG.Value = event.Value;
            app.n = event.Value;
            
            if app.Diameter.Value ~= 0 
                  change(app);
            end
        end

        % Value changed function: SHD
        function SHDValueChanged(app, event)
            type = getParachuteType(app);
            if type(1) == 3
                app.iD = app.SHD.Value;
                app.iR = app.SHD.Value / 2;
            else
                app.sD = app.SHD.Value;
                app.sR = app.SHD.Value / 2;
            end
            
            app.SHDslider.Value = app.SHD.Value;
            
            if app.Diameter.Value ~= 0 && app.NOG.Value ~= 0
                 change(app);
            end
            
             if app.m ~= 0 && app.n ~= 0
               app.DescentVelocityEditField.Value = calcDescVal(app);
            end
        end

        % Value changed function: SHDslider
        function SHDsliderValueChanged(app, event)
            type = getParachuteType(app);
            if type(1) == 3
                app.iD = app.SHD.Value;
                app.iR = app.SHD.Value / 2;
            else
                app.sD = app.SHD.Value;
                app.sR = app.SHD.Value / 2;
            end
            
            app.SHD.Value = app.SHDslider.Value;
            
            if app.Diameter.Value ~= 0 && app.NOG.Value ~= 0
                  change(app);
            end
            
             if app.m ~= 0 && app.n ~= 0
               app.DescentVelocityEditField.Value = calcDescVal(app);
            end
        end

        % Value changing function: SHDslider
        function SHDsliderValueChanging(app, event)
            type = getParachuteType(app);
            if type(1) == 3
                app.iD = app.SHD.Value;
                app.iR = app.SHD.Value / 2;
            else
                app.sD = app.SHD.Value;
                app.sR = app.SHD.Value / 2;
            end
            
            app.SHD.Value = event.Value;
            
            if app.Diameter.Value ~= 0 && app.NOG.Value ~= 0
                change(app);
            end 
            
             if app.m ~= 0 && app.n ~= 0
               app.DescentVelocityEditField.Value = calcDescVal(app);
            end
        end

        % Callback function
        function RocketMassEditFieldValueChanged(app, event)
            value = app.RocketMassEditField.Value;
            app.m = value;
            
            if app.Diameter.Value ~= 0 && app.n ~= 0
               app.DescentVelocityEditField.Value = calcDescVal(app);
            end
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value, 'On') 
                app.CDEditField.Visible = 'on';
                app.cD = app.CDEditField.Value;
            else
                app.CDEditField.Visible = 'off';
                parachuteType = getParachuteType(app);
                pt = parachuteType(1,1);
                    switch pt
                        case 1
                            app.cD = .8;
                        case 2
                            app.cD = 1;
                        case 3
                            app.cD = 2;
                    end
            end
            
            if app.m ~= 0 
               app.DescentVelocityEditField.Value = calcDescVal(app);
            end
            
        end

        % Value changed function: CDEditField
        function CDEditFieldValueChanged(app, event)
            value = app.CDEditField.Value;
            app.cD = value;
                
            if app.m ~= 0 && app.n ~= 0
               app.DescentVelocityEditField.Value = calcDescVal(app);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Selection changed function: ParachuteTypeButtonGroup
        function ParachuteTypeButtonGroupSelectionChanged(app, event)
            selectedButton = getParachuteType(app);
            switch selectedButton(1)
                case 1 %Flat Circular
                    app.cD = selectedButton(2);
                    if app.iR ~= 0
                       app.sR = app.iR; 
                    end
             
                    app.SpillHoleDiameterLabel.Text = 'Spill Hole Diameter';
                    app.SpillHoleDiameterLabel.Position = [7,116,102,22];
                    app.spillholeUnits.Position = [111,115,25,22];
                    
                    if app.Diameter.Value ~= 0 && app.NOG.Value ~= 0
                        change(app);
                    end
                    
                    if app.m ~= 0 && app.n ~= 0
                        app.DescentVelocityEditField.Value = calcDescVal(app);
                    end
                case 2 %Hemispherical
                    app.cD = selectedButton(2); 
                    if app.iR ~= 0
                       app.sR = app.iR; 
                    end
                    
                    app.SpillHoleDiameterLabel.Text = 'Spill Hole Diameter';
                    app.SpillHoleDiameterLabel.Position = [7,116,102,22];
                    app.spillholeUnits.Position = [111,115,25,22];
         
                    if app.Diameter.Value ~= 0 && app.NOG.Value ~= 0
                        change(app);
                    end
                    
                    if app.m ~= 0 && app.n ~= 0
                        app.DescentVelocityEditField.Value = calcDescVal(app);
                    end
                case 3 %Torroidal                
                    app.cD = selectedButton(2);
                    app.iR = app.sR;
                    
                    app.SpillHoleDiameterLabel.Text = 'Inner Diameter';
                    app.SpillHoleDiameterLabel.Position = [7,116,102,22];
                    app.spillholeUnits.Position = [111,115,25,22];
                    
                    if app.Diameter.Value ~= 0 && app.NOG.Value ~= 0 
                        change(app);
                    end
                    
                    if app.m ~= 0 && app.n ~= 0
                        app.DescentVelocityEditField.Value = calcDescVal(app);
                    end
                case 4 %Disk Gap Band
                    app.cD = selectedButton(2);
                    if app.iR ~= 0
                       app.sR = app.iR; 
                    end
                    
                    app.sD = app.SHD.Value; %update spill hole diameter
                    app.SpillHoleDiameterLabel.Text = 'Spill Hole Diameter';
                    app.SpillHoleDiameterLabel.Position = [7,116,102,22];
                    app.spillholeUnits.Position = [111,115,25,22];
                    
                    if app.Diameter.Value ~= 0 && app.NOG.Value ~= 0
                        change(app);
                    end
                    
                    if app.m ~= 0 && app.n ~= 0
                        app.DescentVelocityEditField.Value = calcDescVal(app);
                    end
            end
            
           
        end

        % Value changed function: SeamWidthDropDown
        function SeamWidthDropDownValueChanged(app, event)
            value = app.SeamWidthDropDown.Value;
            switch value
                case '0"'
                    app.seam = 0;
                case '1/4"'
                    app.seam = 1/4;
                case '3/8"'
                    app.seam = 3/8;
                case '1/2"'
                    app.seam = 1/2;
                case '3/4"'
                    app.seam = 3/4;
                case '1"'
                    app.seam = 1;
                case '5/4"'
                    app.seam = 5/4;
                case '3/2"'
                    app.seam = 3/2;
                case '2"'
                    app.seam = 2;
                
            end
            
            if app.Diameter.Value ~= 0 && app.NOG.Value ~= 0 
                    change(app);
            end
        end

        % Value changed function: DXFFileNameEditField
        function DXFFileNameEditFieldValueChanged(app, event)
            value = app.DXFFileNameEditField.Value;
            
        end

        % Value changed function: ExportButton
        function ExportButtonValueChanged(app, event)
            app.ExportButton.Enable;
            if app.Diameter.Value ~= 0 && app.NOG.Value ~= 0 
                fileName = app.DXFFileNameEditField.Value;
                dxf = '.dxf';
                if contains(fileName, dxf) == 0
                   fileName = strcat(fileName,dxf);
                end
                     
                FID = dxf_open(fileName);
                
                %following top half of gore w/ seam
                x1 = linspace(-app.seam, app.zero, 250)';
                y1 = zeros(length(x1),1);
                for i = 1 : length(x1) %creating individual points for plotting .dxf
                    y1(i) = app.wPs(x1(i));
                end
                
                %following top half of gore w/o seam
                x1n = linspace(0, app.zero-app.seam, 250)';
                y1n = zeros(length(x1n),1);
                for i = 1 : length(x1n) %creating individual points for plotting .dxf
                    y1n(i) = app.wP(x1n(i)); %no seam
                end
                
                %vertical line top to bottom near end
                x2 = ones(250,1).*app.zero;
                y2 = linspace(app.wPs(app.zero), app.wNs(app.zero),250)';
                
                %vertical line top to bottom near end
                x2n = ones(250,1).*app.zero;
                x2n = x2n - app.seam; %must subtract seam width
                y2n = linspace(app.wP(app.zero-app.seam), app.wN(app.zero-app.seam), 250)';
                
                %following bottom half (negative portion from tip to base
                x3 = linspace(app.zero, -app.seam, 250)';
                y3 = zeros(length(x3),1);
                for i = 1 : length(x3)
                    y3(i) = app.wNs(x3(i));
                end  
                
                %following bottom half (negative portion from tip to base
                %w/o seam
                x3n = linspace(app.zero - app.seam, 0, 250)';
                y3n = zeros(length(x3),1);
                for i = 1 : length(x3n)
                    y3n(i) = app.wN(x3n(i));
                end 

                %vertical line near base
                x4 = ones(250,1).*(-app.seam);
                y4 = linspace(app.wNs(-app.seam), app.wPs(-app.seam), 250)';
                
                %vertical line near base w/o seam
                x4n = zeros(250,1);
                y4n = linspace(app.wN(0), app.wP(0), 250)';
                
                selectedButton = getParachuteType(app); %find if disk gap band is selected
                
                if selectedButton(1) == 4 %disk gap band has been selected, must include band section
                    x_bn = -app.gap-app.height-6;
                    x_tn = -app.gap-6;
                    
                    y_bn = -app.width/2; %bottom y coordinate
                    y_tn = app.width/2; %top y coordinate
                    
                    x_b = -app.gap-app.height-6-app.seam; %bottom x coordinate
                    x_t = -app.gap-6+app.seam; %top x coordinate
                    
                    y_b = -app.width/2 - app.seam; %bottom y coordinate
                    y_t = app.width/2 + app.seam; %top y coordinate
         
                    BX = cat(1, x_b, x_b, x_t, x_t, x_b);
                    BY = cat(1, y_b, y_t, y_t, y_b, y_b);
                    BZ = zeros(length(BX));
                    
                    BXn = cat(1, x_bn, x_bn, x_tn, x_tn, x_bn);
                    BYn = cat(1, y_bn, y_tn, y_tn, y_bn, y_bn);
                    BZn = zeros(length(BX));
                    
                    dxf_polyline(FID,BXn,BYn,BZn); %no seam width
                    dxf_polyline(FID,BX,BY,BZ); %seam width
                end  
                
                 X = cat(1, x1n, x2n, x3n, x4n);
                 Y = cat(1, y1n, y2n, y3n, y4n);
                 Z = zeros(length(X));
                 
                 Xs = cat(1, x1, x2, x3, x4);
                 Ys = cat(1, y1, y2, y3, y4);
                 Zs = zeros(length(X));
                   
                 dxf_polyline(FID,X,Y,Z);
                 dxf_polyline(FID,Xs,Ys,Zs);

                 dxf_close(FID);
            end
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '2-D Gore')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '3-D Parachute')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.Panel = uipanel(app.UIFigure);
            app.DiameterSlider = uislider(app.Panel);
            app.DiameterSlider.Limits = [0 240];
            app.DiameterSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200 210 220 230 240];
            app.DiameterSlider.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100', '110', '120', '130', '140', '150', '160', '170', '180', '190', '200', '210', '220', '230', '240', ''};
            app.DiameterSlider.ValueChangedFcn = createCallbackFcn(app, @DiameterSliderValueChanged, true);
            app.DiameterSlider.ValueChangingFcn = createCallbackFcn(app, @DiameterSliderValueChanging, true);
            app.DiameterSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100 105 110 115 120 125 130 135 140 145 150 155 160 165 170 175 180 185 190 195 200 205 210 215 220 225 230 235 240];
            app.DiameterEditFieldLabel = uilabel(app.Panel);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.Diameter = uieditfield(app.Panel, 'numeric');
            app.Diameter.ValueChangedFcn = createCallbackFcn(app, @DiameterValueChanged, true);
            app.SHDslider = uislider(app.Panel);
            app.SHDslider.Limits = [0 50];
            app.SHDslider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50];
            app.SHDslider.ValueChangedFcn = createCallbackFcn(app, @SHDsliderValueChanged, true);
            app.SHDslider.ValueChangingFcn = createCallbackFcn(app, @SHDsliderValueChanging, true);
            app.SHDslider.MinorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50];
            app.SpillHoleDiameterLabel = uilabel(app.Panel);
            app.SpillHoleDiameterLabel.Text = 'Spill Hole Diameter';
            app.SHD = uieditfield(app.Panel, 'numeric');
            app.SHD.ValueChangedFcn = createCallbackFcn(app, @SHDValueChanged, true);
            app.ofGoresSpinnerLabel = uilabel(app.Panel);
            app.ofGoresSpinnerLabel.Text = '# of Gores';
            app.NOG = uispinner(app.Panel);
            app.NOG.ValueChangingFcn = createCallbackFcn(app, @NOGValueChanging, true);
            app.NOG.ValueChangedFcn = createCallbackFcn(app, @NOGValueChanged, true);
            app.diameterUnits = uilabel(app.Panel);
            app.diameterUnits.Text = '(in)';
            app.spillholeUnits = uilabel(app.Panel);
            app.spillholeUnits.Text = '(in)';
            app.SeamWidthDropDownLabel = uilabel(app.Panel);
            app.SeamWidthDropDownLabel.Text = 'Seam Width';
            app.SeamWidthDropDown = uidropdown(app.Panel);
            app.SeamWidthDropDown.Items = {'0"', '1/4"', '3/8"', '1/2"', '3/4"', '1"', '5/4"', '3/2"', '2"'};
            app.SeamWidthDropDown.ValueChangedFcn = createCallbackFcn(app, @SeamWidthDropDownValueChanged, true);
            app.SeamWidthDropDown.Value = '1/4"';
            app.ExportasDXFfileExportsBothLinesPanel = uipanel(app.Panel);
            app.ExportasDXFfileExportsBothLinesPanel.Title = 'Export as DXF file (Exports Both Lines)';
            app.DXFFileNameEditFieldLabel = uilabel(app.ExportasDXFfileExportsBothLinesPanel);
            app.DXFFileNameEditFieldLabel.Text = 'DXF File Name';
            app.DXFFileNameEditField = uieditfield(app.ExportasDXFfileExportsBothLinesPanel, 'text');
            app.DXFFileNameEditField.ValueChangedFcn = createCallbackFcn(app, @DXFFileNameEditFieldValueChanged, true);
            app.DXFFileNameEditField.Value = 'MyParachute';
            app.ExportButton = uibutton(app.ExportasDXFfileExportsBothLinesPanel, 'state');
            app.ExportButton.ValueChangedFcn = createCallbackFcn(app, @ExportButtonValueChanged, true);
            app.ExportButton.Text = 'Export';
            app.ParachuteTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.ParachuteTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ParachuteTypeButtonGroupSelectionChanged, true);
            app.ParachuteTypeButtonGroup.Title = 'Parachute Type';
            app.FlatCircularButton = uiradiobutton(app.ParachuteTypeButtonGroup);
            app.FlatCircularButton.Text = 'Flat Circular';
            app.FlatCircularButton.Value = true;
            app.HemisphericalButton = uiradiobutton(app.ParachuteTypeButtonGroup);
            app.HemisphericalButton.Text = 'Hemispherical';
            app.TorridialButton = uiradiobutton(app.ParachuteTypeButtonGroup);
            app.TorridialButton.Text = 'Torridial';
            app.CD10Label = uilabel(app.ParachuteTypeButtonGroup);
            app.CD10Label.Text = '*CD = 1.0';
            app.CD20Label = uilabel(app.ParachuteTypeButtonGroup);
            app.CD20Label.Text = '*CD = 2.0';
            app.CD08Label = uilabel(app.ParachuteTypeButtonGroup);
            app.CD08Label.Text = '*CD = 0.8';
            app.CDLabel = uilabel(app.ParachuteTypeButtonGroup);
            app.CDLabel.Text = 'CD =';
            app.CDEditField = uieditfield(app.ParachuteTypeButtonGroup, 'numeric');
            app.CDEditField.ValueChangedFcn = createCallbackFcn(app, @CDEditFieldValueChanged, true);
            app.CDEditField.Value = 1;
            app.Switch = uiswitch(app.ParachuteTypeButtonGroup, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.OverrideCDLabel = uilabel(app.ParachuteTypeButtonGroup);
            app.OverrideCDLabel.Text = '*Override CD';
            app.DiskGapBandButton = uiradiobutton(app.ParachuteTypeButtonGroup);
            app.DiskGapBandButton.Text = 'Disk-Gap-Band';
            app.CD05Label = uilabel(app.ParachuteTypeButtonGroup);
            app.CD05Label.Text = '*CD = 0.5';
            app.ParachuteVisualizationGoreTemplateCreatorLabel = uilabel(app.UIFigure);
            app.ParachuteVisualizationGoreTemplateCreatorLabel.Text = '      Parachute Visualization / Gore Template Creator';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = parachuteApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end